classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GreetButton     matlab.ui.control.Button
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        SomeViewModel SomeViewModel  
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, someViewModel)
            app.SomeViewModel = someViewModel;
            app.SomeViewModel.registerListener();
            
            app.EditField.Value = "Change ME!";
            
            % Set commands
            micromvvm.Binder.setCommandAndCallback(app.GreetButton, "ButtonPushedFcn", app.SomeViewModel.displayHelloWorld());
            
            % 
            app.SomeViewModel.registerBinding(app.EditField, 'Greetings');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreetButton = uibutton(app.UIFigure, 'push');
            app.GreetButton.Text = 'Greet';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end